classdef first_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SlimaniAbdellatifLabel    matlab.ui.control.Label
        profedencadremmentLabel   matlab.ui.control.Label
        RaliserparLabel           matlab.ui.control.Label
        RAHHALELBOUDALILabel      matlab.ui.control.Label
        Data2EditField            matlab.ui.control.EditField
        Data2EditFieldLabel       matlab.ui.control.Label
        Data1EditField            matlab.ui.control.EditField
        Data1EditFieldLabel       matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        racinesButton             matlab.ui.control.Button
        polynomeButton            matlab.ui.control.Button
        ELGHARISMOHAMEDLabel      matlab.ui.control.Label
        ProjetdefindemoduleLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        polynome % Description
        racines % Description
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: polynomeButton
        function polynomeButtonPushed(app, event)
            %polynome
            app.polynome=polynome(app,app.Data1EditField.Value,app.Data2EditField.Value);
            delete(app);
        end

        % Button pushed function: racinesButton
        function racinesButtonPushed(app, event)
            %racines
            app.racines=racines(app,app.Data1EditField.Value,app.Data2EditField.Value);
            delete(app);
        end

        % Value changed function: Data1EditField
        function Data1EditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: Data2EditField
        function Data2EditFieldValueChanged(app, event)
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'c255a4dbeebd895aca2015289df9c168d1992592.jpeg';
            app.ProjetdefindemoduleLabel = uilabel(app.UIFigure);
            app.ProjetdefindemoduleLabel.Text = 'Projet de fin de module';
            app.ELGHARISMOHAMEDLabel = uilabel(app.UIFigure);
            app.ELGHARISMOHAMEDLabel.Text = 'EL-GHARIS MOHAMED';
            app.polynomeButton = uibutton(app.UIFigure, 'push');
            app.polynomeButton.ButtonPushedFcn = createCallbackFcn(app, @polynomeButtonPushed, true);
            app.polynomeButton.Text = 'polynome';
            app.racinesButton = uibutton(app.UIFigure, 'push');
            app.racinesButton.ButtonPushedFcn = createCallbackFcn(app, @racinesButtonPushed, true);
            app.racinesButton.Text = 'racines';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'logo-eidia.jpg';
            app.Data1EditFieldLabel = uilabel(app.UIFigure);
            app.Data1EditFieldLabel.Text = 'Data 1';
            app.Data1EditField = uieditfield(app.UIFigure, 'text');
            app.Data1EditField.ValueChangedFcn = createCallbackFcn(app, @Data1EditFieldValueChanged, true);
            app.Data2EditFieldLabel = uilabel(app.UIFigure);
            app.Data2EditFieldLabel.Text = 'Data 2';
            app.Data2EditField = uieditfield(app.UIFigure, 'text');
            app.Data2EditField.ValueChangedFcn = createCallbackFcn(app, @Data2EditFieldValueChanged, true);
            app.RAHHALELBOUDALILabel = uilabel(app.UIFigure);
            app.RAHHALELBOUDALILabel.Text = 'RAHHAL EL BOUDALI';
            app.RaliserparLabel = uilabel(app.UIFigure);
            app.RaliserparLabel.Text = 'Réaliser par:';
            app.profedencadremmentLabel = uilabel(app.UIFigure);
            app.profedencadremmentLabel.Text = 'profe d''encadremment:';
            app.SlimaniAbdellatifLabel = uilabel(app.UIFigure);
            app.SlimaniAbdellatifLabel.Text = 'Slimani Abdellatif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end