classdef graph_polynome < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MotifDropDown        matlab.ui.control.DropDown
        MotifDropDownLabel   matlab.ui.control.Label
        ColorDropDown        matlab.ui.control.DropDown
        ColorDropDownLabel   matlab.ui.control.Label
        backButton           matlab.ui.control.Button
        finEditField         matlab.ui.control.NumericEditField
        finEditFieldLabel    matlab.ui.control.Label
        debutEditField       matlab.ui.control.NumericEditField
        debutEditFieldLabel  matlab.ui.control.Label
        poly_DivButton       matlab.ui.control.Button
        poly_ProdButton      matlab.ui.control.Button
        Poly_subButton       matlab.ui.control.Button
        Poly_AddButton       matlab.ui.control.Button
        Poly2Button          matlab.ui.control.Button
        Poly1Button          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    properties (Access = public)
        poly1 % Description
        poly2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, polynome, data1, data2)
            app.callingapp=polynome;
            app.poly1=data1;
            app.poly2=data2;
        end

        % Button pushed function: Poly1Button
        function Poly1ButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            
            g=plot(app.UIAxes,x,polyval(P1Converted,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Value changed function: debutEditField
        function debutEditFieldValueChanged(app, event)
            value = app.debutEditField.Value;
            
        end

        % Value changed function: finEditField
        function finEditFieldValueChanged(app, event)
            value = app.finEditField.Value;
            
        end

        % Button pushed function: Poly_AddButton
        function Poly_AddButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = P1Converted+P2Converted;
            
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: Poly_subButton
        function Poly_subButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = P1Converted-P2Converted;
            
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly_ProdButton
        function poly_ProdButtonPushed(app, event)
             debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));

            Pt = conv(P1Converted,P2Converted);
           
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly_DivButton
        function poly_DivButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));

            Pt = deconv(P1Converted,P2Converted);
            
            g=plot(app.UIAxes,x,polyval(pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: Poly2Button
        function Poly2ButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P2=app.poly1;
                P2Converted=str2double(strsplit(P2,' '));
           
            g=plot(app.UIAxes,x,polyval(P2Converted,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            polynome;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Poly1Button = uibutton(app.UIFigure, 'push');
            app.Poly1Button.ButtonPushedFcn = createCallbackFcn(app, @Poly1ButtonPushed, true);
            app.Poly1Button.Text = 'Poly1';
            app.Poly2Button = uibutton(app.UIFigure, 'push');
            app.Poly2Button.ButtonPushedFcn = createCallbackFcn(app, @Poly2ButtonPushed, true);
            app.Poly2Button.Text = 'Poly2';
            app.Poly_AddButton = uibutton(app.UIFigure, 'push');
            app.Poly_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Poly_AddButtonPushed, true);
            app.Poly_AddButton.Text = 'Poly_Add';
            app.Poly_subButton = uibutton(app.UIFigure, 'push');
            app.Poly_subButton.ButtonPushedFcn = createCallbackFcn(app, @Poly_subButtonPushed, true);
            app.Poly_subButton.Text = 'Poly_sub';
            app.poly_ProdButton = uibutton(app.UIFigure, 'push');
            app.poly_ProdButton.ButtonPushedFcn = createCallbackFcn(app, @poly_ProdButtonPushed, true);
            app.poly_ProdButton.Text = 'poly_Prod';
            app.poly_DivButton = uibutton(app.UIFigure, 'push');
            app.poly_DivButton.ButtonPushedFcn = createCallbackFcn(app, @poly_DivButtonPushed, true);
            app.poly_DivButton.Text = 'poly_Div';
            app.debutEditFieldLabel = uilabel(app.UIFigure);
            app.debutEditFieldLabel.Text = 'debut';
            app.debutEditField = uieditfield(app.UIFigure, 'numeric');
            app.debutEditField.ValueChangedFcn = createCallbackFcn(app, @debutEditFieldValueChanged, true);
            app.finEditFieldLabel = uilabel(app.UIFigure);
            app.finEditFieldLabel.Text = 'fin';
            app.finEditField = uieditfield(app.UIFigure, 'numeric');
            app.finEditField.ValueChangedFcn = createCallbackFcn(app, @finEditFieldValueChanged, true);
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'black', 'green', 'yellow', 'blue'};
            app.ColorDropDown.Value = 'red';
            app.MotifDropDownLabel = uilabel(app.UIFigure);
            app.MotifDropDownLabel.Text = 'Motif';
            app.MotifDropDown = uidropdown(app.UIFigure);
            app.MotifDropDown.Items = {'+', '*', '-', 'o'};
            app.MotifDropDown.Value = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_polynome(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end