classdef graph_racines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MotifDropDown        matlab.ui.control.DropDown
        MotifDropDownLabel   matlab.ui.control.Label
        ColorDropDown        matlab.ui.control.DropDown
        ColorDropDownLabel   matlab.ui.control.Label
        backButton           matlab.ui.control.Button
        poly_DivButton       matlab.ui.control.Button
        poly_prodButton      matlab.ui.control.Button
        poly_subButton       matlab.ui.control.Button
        poly_AddButton       matlab.ui.control.Button
        poly2Button          matlab.ui.control.Button
        poly1Button          matlab.ui.control.Button
        finEditField         matlab.ui.control.NumericEditField
        finEditFieldLabel    matlab.ui.control.Label
        debutEditField       matlab.ui.control.NumericEditField
        debutEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    properties (Access = public)
        rac1 % Description
        rac2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, racines, data1, data2)
            app.callingapp=racines;
            app.rac1=data1;
            app.rac2=data2;
        end

        % Button pushed function: poly1Button
        function poly1ButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
             P1=app.rac1;
                P1Converted=str2double(strsplit(P1,' '));
            Pt = poly(P1Converted');
           
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly2Button
        function poly2ButtonPushed(app, event)
             debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
             P2=app.rac2;
                P2Converted=str2double(strsplit(P2,' '));
            Pt = poly(P2Converted');
            
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly_AddButton
        function poly_AddButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.rac1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.rac2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = poly(P1Converted')+poly(P2Converted');
            
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly_subButton
        function poly_subButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.rac1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.rac2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = poly(P1Converted')-poly(P2Converted');
           
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly_prodButton
        function poly_prodButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.rac1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.rac2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = conv(poly(P1Converted'),poly(P2Converted'));
            
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: poly_DivButton
        function poly_DivButtonPushed(app, event)
            debut=app.debutEditField.Value;
            fin=app.finEditField.Value;
            x=debut:0.1:fin;
            P1=app.rac1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.rac2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = conv(poly(P1Converted'),poly(P2Converted'));
         
            g=plot(app.UIAxes,x,polyval(Pt,x));
            g.Color=app.ColorDropDown.Value;
            g.Marker=app.MotifDropDown.Value;
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            racines;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.debutEditFieldLabel = uilabel(app.UIFigure);
            app.debutEditFieldLabel.Text = 'debut';
            app.debutEditField = uieditfield(app.UIFigure, 'numeric');
            app.finEditFieldLabel = uilabel(app.UIFigure);
            app.finEditFieldLabel.Text = 'fin';
            app.finEditField = uieditfield(app.UIFigure, 'numeric');
            app.poly1Button = uibutton(app.UIFigure, 'push');
            app.poly1Button.ButtonPushedFcn = createCallbackFcn(app, @poly1ButtonPushed, true);
            app.poly1Button.Text = 'poly1';
            app.poly2Button = uibutton(app.UIFigure, 'push');
            app.poly2Button.ButtonPushedFcn = createCallbackFcn(app, @poly2ButtonPushed, true);
            app.poly2Button.Text = 'poly2';
            app.poly_AddButton = uibutton(app.UIFigure, 'push');
            app.poly_AddButton.ButtonPushedFcn = createCallbackFcn(app, @poly_AddButtonPushed, true);
            app.poly_AddButton.Text = 'poly_Add';
            app.poly_subButton = uibutton(app.UIFigure, 'push');
            app.poly_subButton.ButtonPushedFcn = createCallbackFcn(app, @poly_subButtonPushed, true);
            app.poly_subButton.Text = 'poly_sub';
            app.poly_prodButton = uibutton(app.UIFigure, 'push');
            app.poly_prodButton.ButtonPushedFcn = createCallbackFcn(app, @poly_prodButtonPushed, true);
            app.poly_prodButton.Text = 'poly_prod';
            app.poly_DivButton = uibutton(app.UIFigure, 'push');
            app.poly_DivButton.ButtonPushedFcn = createCallbackFcn(app, @poly_DivButtonPushed, true);
            app.poly_DivButton.Text = 'poly_Div';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'black', 'blue', 'green'};
            app.ColorDropDown.Value = 'red';
            app.MotifDropDownLabel = uilabel(app.UIFigure);
            app.MotifDropDownLabel.Text = 'Motif';
            app.MotifDropDown = uidropdown(app.UIFigure);
            app.MotifDropDown.Items = {'+', '-', '*', 'o'};
            app.MotifDropDown.Value = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_racines(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end