classdef polynome < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BackButton              matlab.ui.control.Button
        racine_poly_divButton   matlab.ui.control.Button
        racine_poly_ProdButton  matlab.ui.control.Button
        racine_poly_SubButton   matlab.ui.control.Button
        racine_poly_AddButton   matlab.ui.control.Button
        racine_poly2Button      matlab.ui.control.Button
        resultatEditField       matlab.ui.control.EditField
        resultatEditFieldLabel  matlab.ui.control.Label
        graphButton             matlab.ui.control.Button
        racine_poly1Button      matlab.ui.control.Button
        divitionButton          matlab.ui.control.Button
        multiplicationButton    matlab.ui.control.Button
        soustractionButton      matlab.ui.control.Button
        additionButton          matlab.ui.control.Button
        PolynomeLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    properties (Access = public)
        poly1 % Description
        poly2 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, first_page, data1, data2)
            app.callingapp=first_page;
            app.poly1=data1;
            app.poly2=data2;
        end

        % Button pushed function: additionButton
        function additionButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = P1Converted+P2Converted;
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: soustractionButton
        function soustractionButtonPushed(app, event)
             P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));

            Pt = P1Converted-P2Converted;
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: multiplicationButton
        function multiplicationButtonPushed(app, event)
             P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));

            Pt = conv(P1Converted,P2Converted);
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: divitionButton
        function divitionButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));

            Pt = deconv(P1Converted,P2Converted);
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: racine_poly1Button
        function racine_poly1ButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            Pt = roots(P1Converted);
            k=num2str(Pt');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: racine_poly2Button
        function racine_poly2ButtonPushed(app, event)
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));

            Pt = roots(P2Converted);
            k=num2str(Pt');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: racine_poly_AddButton
        function racine_poly_AddButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = P1Converted+P2Converted;
            r = roots(Pt);
            k=num2str(r');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: racine_poly_SubButton
        function racine_poly_SubButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = P1Converted-P2Converted;
            r = roots(Pt);
            k=num2str(r');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: racine_poly_ProdButton
        function racine_poly_ProdButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = conv(P1Converted,P2Converted);
            r = roots(Pt);
            k=num2str(r');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: racine_poly_divButton
        function racine_poly_divButtonPushed(app, event)
            P1=app.poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = deconv(P1Converted,P2Converted);
            r = roots(Pt);
            k=num2str(r');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: graphButton
        function graphButtonPushed(app, event)
            app.graph_polynome=graph_polynome(app,app.poly1,app.poly2);
            delete(app);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            first_page;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PolynomeLabel = uilabel(app.UIFigure);
            app.PolynomeLabel.Text = 'Polynome';
            app.additionButton = uibutton(app.UIFigure, 'push');
            app.additionButton.ButtonPushedFcn = createCallbackFcn(app, @additionButtonPushed, true);
            app.additionButton.Text = 'addition';
            app.soustractionButton = uibutton(app.UIFigure, 'push');
            app.soustractionButton.ButtonPushedFcn = createCallbackFcn(app, @soustractionButtonPushed, true);
            app.soustractionButton.Text = 'soustraction';
            app.multiplicationButton = uibutton(app.UIFigure, 'push');
            app.multiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @multiplicationButtonPushed, true);
            app.multiplicationButton.Text = 'multiplication';
            app.divitionButton = uibutton(app.UIFigure, 'push');
            app.divitionButton.ButtonPushedFcn = createCallbackFcn(app, @divitionButtonPushed, true);
            app.divitionButton.Text = 'divition';
            app.racine_poly1Button = uibutton(app.UIFigure, 'push');
            app.racine_poly1Button.ButtonPushedFcn = createCallbackFcn(app, @racine_poly1ButtonPushed, true);
            app.racine_poly1Button.Text = 'racine_poly1';
            app.graphButton = uibutton(app.UIFigure, 'push');
            app.graphButton.ButtonPushedFcn = createCallbackFcn(app, @graphButtonPushed, true);
            app.graphButton.Text = 'graph';
            app.resultatEditFieldLabel = uilabel(app.UIFigure);
            app.resultatEditFieldLabel.Text = 'resultat';
            app.resultatEditField = uieditfield(app.UIFigure, 'text');
            app.racine_poly2Button = uibutton(app.UIFigure, 'push');
            app.racine_poly2Button.ButtonPushedFcn = createCallbackFcn(app, @racine_poly2ButtonPushed, true);
            app.racine_poly2Button.Text = 'racine_poly2';
            app.racine_poly_AddButton = uibutton(app.UIFigure, 'push');
            app.racine_poly_AddButton.ButtonPushedFcn = createCallbackFcn(app, @racine_poly_AddButtonPushed, true);
            app.racine_poly_AddButton.Text = 'racine_poly_Add';
            app.racine_poly_SubButton = uibutton(app.UIFigure, 'push');
            app.racine_poly_SubButton.ButtonPushedFcn = createCallbackFcn(app, @racine_poly_SubButtonPushed, true);
            app.racine_poly_SubButton.Text = 'racine_poly_Sub';
            app.racine_poly_ProdButton = uibutton(app.UIFigure, 'push');
            app.racine_poly_ProdButton.ButtonPushedFcn = createCallbackFcn(app, @racine_poly_ProdButtonPushed, true);
            app.racine_poly_ProdButton.Text = 'racine_poly_Prod';
            app.racine_poly_divButton = uibutton(app.UIFigure, 'push');
            app.racine_poly_divButton.ButtonPushedFcn = createCallbackFcn(app, @racine_poly_divButtonPushed, true);
            app.racine_poly_divButton.Text = 'racine_poly_div';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polynome(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end