classdef racines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BackButton              matlab.ui.control.Button
        resultatEditField       matlab.ui.control.EditField
        resultatEditFieldLabel  matlab.ui.control.Label
        graph_poly2Button       matlab.ui.control.Button
        graphButton             matlab.ui.control.Button
        DivButton               matlab.ui.control.Button
        ProdButton              matlab.ui.control.Button
        SousButton              matlab.ui.control.Button
        AddButton               matlab.ui.control.Button
        generate_poly2Button    matlab.ui.control.Button
        generate_poly1Button    matlab.ui.control.Button
        racinesLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    properties (Access = public)
        racine_poly1 % Description
        racine_poly2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, first_page, data1, data2)
            app.callingapp=first_page;
            app.racine_poly1=data1;
            app.racine_poly2=data2;
        end

        % Button pushed function: generate_poly1Button
        function generate_poly1ButtonPushed(app, event)
            P1=app.racine_poly1;
                P1Converted=str2double(strsplit(P1,' '));
            Pt = poly(P1Converted');
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: generate_poly2Button
        function generate_poly2ButtonPushed(app, event)
            P2=app.racine_poly2;
                P2Converted=str2double(strsplit(P2,' '));
            Pt = poly(P2Converted');
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            P1=app.racine_poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.racine_poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = poly(P1Converted')+poly(P2Converted');
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: SousButton
        function SousButtonPushed(app, event)
            P1=app.racine_poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.racine_poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = poly(P1Converted')-poly(P2Converted');
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: ProdButton
        function ProdButtonPushed(app, event)
            P1=app.racine_poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.racine_poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = conv(poly(P1Converted'),poly(P2Converted'));
            k=num2str(Pt);
            app.resultatEditField.Value=k;
        end

        % Button pushed function: DivButton
        function DivButtonPushed(app, event)
            P1=app.racine_poly1;
                P1Converted=str2double(strsplit(P1,' '));
            P2=app.racine_poly2;
                P2Converted=str2double(strsplit(P2,' '));
            

            Pt = deconv(poly(P1Converted'),poly(P2Converted'));
            k=num2str(Pt');
            app.resultatEditField.Value=k;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            first_page;
            delete(app);
        end

        % Button pushed function: graphButton
        function graphButtonPushed(app, event)
            app.graph_racines=graph_racines(app,app.racine_poly1,app.racine_poly2);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.racinesLabel = uilabel(app.UIFigure);
            app.racinesLabel.Text = 'racines';
            app.generate_poly1Button = uibutton(app.UIFigure, 'push');
            app.generate_poly1Button.ButtonPushedFcn = createCallbackFcn(app, @generate_poly1ButtonPushed, true);
            app.generate_poly1Button.Text = 'generate_poly1';
            app.generate_poly2Button = uibutton(app.UIFigure, 'push');
            app.generate_poly2Button.ButtonPushedFcn = createCallbackFcn(app, @generate_poly2ButtonPushed, true);
            app.generate_poly2Button.Text = 'generate_poly2';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SousButton = uibutton(app.UIFigure, 'push');
            app.SousButton.ButtonPushedFcn = createCallbackFcn(app, @SousButtonPushed, true);
            app.SousButton.Text = 'Sous';
            app.ProdButton = uibutton(app.UIFigure, 'push');
            app.ProdButton.ButtonPushedFcn = createCallbackFcn(app, @ProdButtonPushed, true);
            app.ProdButton.Text = 'Prod';
            app.DivButton = uibutton(app.UIFigure, 'push');
            app.DivButton.ButtonPushedFcn = createCallbackFcn(app, @DivButtonPushed, true);
            app.DivButton.Text = 'Div';
            app.graphButton = uibutton(app.UIFigure, 'push');
            app.graphButton.ButtonPushedFcn = createCallbackFcn(app, @graphButtonPushed, true);
            app.graphButton.Text = 'graph';
            app.graph_poly2Button = uibutton(app.UIFigure, 'push');
            app.graph_poly2Button.Text = 'graph_poly2';
            app.resultatEditFieldLabel = uilabel(app.UIFigure);
            app.resultatEditFieldLabel.Text = 'resultat';
            app.resultatEditField = uieditfield(app.UIFigure, 'text');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = racines(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end